classdef LPC_Vocoder_Interim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AnalysisPanel                matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        InputPanel                   matlab.ui.container.Panel
        RecordnowButtonGroup         matlab.ui.container.ButtonGroup
        RecordAudioButton            matlab.ui.control.Button
        PlaySynthesizedAudioButton   matlab.ui.control.Button
        DurationsTextAreaLabel       matlab.ui.control.Label
        DurationsTextArea            matlab.ui.control.TextArea
        LoadRecordedAudioButton      matlab.ui.control.Button
        ORLabel                      matlab.ui.control.Label
        PrerecordedButtonGroup       matlab.ui.container.ButtonGroup
        LoadAudioButton              matlab.ui.control.Button
        PlaySynthesizedAudiioButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: RecordAudioButton
        function RecordAudioButtonPushed(app, event)

                    
   global y
   
            audioObject = audiorecorder;     
            Fs = audioObject.SampleRate;
            duration = str2double(app.DurationsTextArea.Value{1});
            msgbox('Recording');
            recordblocking(audioObject, duration);
            msgbox('Done');   
            y = getaudiodata(audioObject);
            %plot(app.UIAxes2, spectrogram(y));          
%             i=strfind(file,'.');
%             filename=file(1:i-1);
%             filetype=file(i:end);
            Fsd=8000;
%             filename_new=strcat(filename,'_synth_',int2str(Fsd),'.wav');
            audiowrite('recorded_audio.wav',y/2,Fsd);


        end

        % Button pushed function: PlaySynthesizedAudioButton
        function PlaySynthesizedAudioButtonPushed(app, event)
            
            global sout2
            sound(sout2, 16000);
         
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
            audioObject = evalin('base', 'audioObject');
            y = getaudiodata(audioObject);
            %Fs = audioObject.SampleRate;
            value = app.Switch.Value;
            if(value)
                plot(app.UIAxes2, spectrogram(y));
            else
                x = lpc(y);
                plot(app.UIAxes2, spectrogram(x));
            end
                
        end

        % Callback function
        function UpdateButtonValueChanged(app, event)
                                                                
        end

        % Callback function
        function UpdateButtonPushed(app, event)
         
        end

        % Button pushed function: LoadAudioButton
        function LoadAudioButtonPushed(app, event)
            
            %[FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
            FileName = 'bernie_mike_14700.wav';

%Fs = audioObject.SampleRate;
global sout;

            duration = str2double(app.DurationsTextArea.Value{1});
    
            %y = getaudiodata(audioObject);
            [y, Fs] = audioread(FileName);
            plot(app.UIAxes, (1/Fs)*(0:length(y)-1), y);
            %plot(app.UIAxes2, spectrogram(y));
            %assignin('base', 'audioObject', audioObject);
            
            %disp(y);
            
            plot(app.UIAxes_2, (1/16000)*(0:length(sout)-1), sout);
            
                [s,f,t]=spectrogram(y(:,1),hamming(240),120,Fs);
                mags=s.*conj(s);
                image(app.UIAxes2,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes2, 'YDir','normal');
                ylim(app.UIAxes2,[t(1) t(end)]);
                xlim(app.UIAxes2,[f(1) f(end)]);
                %colorbar(app.UIAxes2);
                
                
                reconstructed = sout;
                [s,f,t]=spectrogram(reconstructed,hamming(240),120,16000);
                
                mags=real(s.*conj(s));
                %disp(mags);
                image(app.UIAxes3,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes3, 'YDir','normal');
                ylim(app.UIAxes3,[t(1) t(end)]);
                xlim(app.UIAxes3,[f(1) f(end)]);


        end

        % Button pushed function: PlaySynthesizedAudiioButton
        function PlaySynthesizedAudiioButtonPushed(app, event)
            
            global sout
            sound(sout, 16000);
            
        end

        % Button pushed function: LoadRecordedAudioButton
        function LoadRecordedAudioButtonPushed(app, event)
           
             %[FileName, PathName] = uigetfile({'*.wav; *.aif'}, 'Select audio file');
            FileName = 'recorded_audio.wav';

%Fs = audioObject.SampleRate;
global sout2;

            duration = str2double(app.DurationsTextArea.Value{1});
    
            %y = getaudiodata(audioObject);
            [y, Fs] = audioread(FileName);
            plot(app.UIAxes, (1/Fs)*(0:length(y)-1), y);
            %plot(app.UIAxes2, spectrogram(y));
            %assignin('base', 'audioObject', audioObject);
            
            %disp(y);
            
            %assignin('base', 'audioObject', audioObject);
            
                [s,f,t]=spectrogram(y,hamming(240),120,16000);
                mags=real(s.*conj(s));
                image(app.UIAxes2,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes2, 'YDir','normal');
                ylim(app.UIAxes2,[t(1) t(end)]);
                xlim(app.UIAxes2,[f(1) f(end)]);
                %colorbar(app.UIAxes2);
                
                reconstructed = sout2;
                [s,f,t]=spectrogram(reconstructed,hamming(240),120,16000);
                
                mags=real(s.*conj(s));
                %disp(mags);
                image(app.UIAxes3,f, t, -10.*log10(mags/max(max(mags))));
                set(app.UIAxes3, 'YDir','normal');
                ylim(app.UIAxes3,[t(1) t(end)]);
                xlim(app.UIAxes3,[f(1) f(end)]);
                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AnalysisPanel = uipanel(app.UIFigure);
            app.AnalysisPanel.Title = 'Analysis';
            app.UIAxes2 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes2, 'Original audio spectrogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes3, 'Reconstructed audio spectrogram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes = uiaxes(app.AnalysisPanel);
            title(app.UIAxes, 'Recorded Audio Signal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.AnalysisPanel);
            title(app.UIAxes_2, 'Reconstructed Audio Signal')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.RecordnowButtonGroup = uibuttongroup(app.InputPanel);
            app.RecordnowButtonGroup.Title = 'Record now';
            app.RecordAudioButton = uibutton(app.RecordnowButtonGroup, 'push');
            app.RecordAudioButton.ButtonPushedFcn = createCallbackFcn(app, @RecordAudioButtonPushed, true);
            app.RecordAudioButton.Text = 'Record Audio';
            app.PlaySynthesizedAudioButton = uibutton(app.RecordnowButtonGroup, 'push');
            app.PlaySynthesizedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySynthesizedAudioButtonPushed, true);
            app.PlaySynthesizedAudioButton.Text = 'Play Synthesized Audio';
            app.DurationsTextAreaLabel = uilabel(app.RecordnowButtonGroup);
            app.DurationsTextAreaLabel.Text = 'Duration (s)';
            app.DurationsTextArea = uitextarea(app.RecordnowButtonGroup);
            app.DurationsTextArea.Value = {'2'};
            app.LoadRecordedAudioButton = uibutton(app.RecordnowButtonGroup, 'push');
            app.LoadRecordedAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadRecordedAudioButtonPushed, true);
            app.LoadRecordedAudioButton.Text = 'Load Recorded Audio';
            app.ORLabel = uilabel(app.InputPanel);
            app.ORLabel.Text = 'OR';
            app.PrerecordedButtonGroup = uibuttongroup(app.InputPanel);
            app.PrerecordedButtonGroup.Title = 'Pre-recorded';
            app.LoadAudioButton = uibutton(app.PrerecordedButtonGroup, 'push');
            app.LoadAudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAudioButtonPushed, true);
            app.LoadAudioButton.Text = 'Load Audio';
            app.PlaySynthesizedAudiioButton = uibutton(app.PrerecordedButtonGroup, 'push');
            app.PlaySynthesizedAudiioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySynthesizedAudiioButtonPushed, true);
            app.PlaySynthesizedAudiioButton.Text = 'Play Synthesized Audiio';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LPC_Vocoder_Interim_app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end